classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GloveDefectsDetectionSystemUIFigure  matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        UploadImageButton  matlab.ui.control.Button
        Image              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif'}, 'Select Image File');
            
            % Check if user cancels selection
            if isequal(file, 0)
                disp('User cancelled image selection.');
                return;
            end

            img = imread(fullfile(path, file));

            img = fn.gloves_defects_detection(img);

            app.Image.ImageSource = img;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GloveDefectsDetectionSystemUIFigure = uifigure('Visible', 'off');
            app.GloveDefectsDetectionSystemUIFigure.Name = 'Glove Defects Detection System';
            app.GloveDefectsDetectionSystemUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.GridLayout = uigridlayout(app.GloveDefectsDetectionSystemUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gloves', 'logo.jpg');
            app.UploadImageButton = uibutton(app.GridLayout, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            % Show the figure after all components are created
            app.GloveDefectsDetectionSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GloveDefectsDetectionSystemUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GloveDefectsDetectionSystemUIFigure)
        end
    end
end